classdef vehiclerecognitionapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PredictionResultLabel        matlab.ui.control.Label
        PredictionLabel              matlab.ui.control.Label
        PredictButton                matlab.ui.control.Button
        PredictionModeDropDown       matlab.ui.control.DropDown
        PredictionModeDropDownLabel  matlab.ui.control.Label
        LoadImageButton              matlab.ui.control.Button
        Image                        matlab.ui.control.Image
    end

    properties (Access = private)
        currentImage % Loaded image data
        svmModel % Trained SVM model
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;
            end
            
            % Read the image from the selected file
            imgPath = fullfile(path, file);
            app.currentImage = imread(imgPath);
            app.Image.ImageSource = imgPath;
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % Check if an image is loaded
            if isempty(app.currentImage)
                uialert(app.UIFigure, 'No image loaded.', 'Error');
                return;
            end
        
            % Load SVM model if not already loaded
            if isempty(app.svmModel)
                if isfile('svmModel.mat')
                    loadedData = load('svmModel.mat');
                    app.svmModel = loadedData.svmModel;
                else
                    uialert(app.UIFigure, 'SVM model not found. Train the model first.', 'Error');
                    return;
                end
            end
        
            % Preprocess the input image
            img = app.currentImage;
            imgGray = rgb2gray(img); % Convert to grayscale
            imgResized = imresize(imgGray, [64 64]); % Resize to match training size
            features = extractHOGFeatures(imgResized); % Extract HOG features
        
            % Predict using the trained SVM model
            predictedLabel = predict(app.svmModel, features);
        
            % Display prediction result
            app.PredictionResultLabel.Text = char(predictedLabel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.PredictionModeDropDownLabel = uilabel(app.UIFigure);
            app.PredictionModeDropDownLabel.Text = 'Prediction Mode';
            app.PredictionModeDropDown = uidropdown(app.UIFigure);
            app.PredictionModeDropDown.Items = {'Non-CNN', 'CNN'};
            app.PredictionModeDropDown.Value = 'Non-CNN';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.PredictionLabel = uilabel(app.UIFigure);
            app.PredictionLabel.Text = 'Prediction:';
            app.PredictionResultLabel = uilabel(app.UIFigure);
            app.PredictionResultLabel.Text = 'Prediction Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vehiclerecognitionapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end